classdef PRoyectofinal_OA_LJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        PuertoComEditFieldLabel        matlab.ui.control.Label
        PuertoComEditField             matlab.ui.control.EditField
        ConectarButton                 matlab.ui.control.Button
        TiempodesenialmuestradasEditFieldLabel  matlab.ui.control.Label
        TiempodesenialmuestradasEditField  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
        GananciaLabel                  matlab.ui.control.Label
        GananciaEditField              matlab.ui.control.NumericEditField
        AplicarFiltroButton            matlab.ui.control.Button
        FrecunciadecorteHzEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzEditField    matlab.ui.control.NumericEditField
        FiltrosDropDownLabel           matlab.ui.control.Label
        FiltrosDropDown                matlab.ui.control.DropDown
        TipodeFiltrosDropDownLabel     matlab.ui.control.Label
        TipodeFiltrosDropDown          matlab.ui.control.DropDown
        FormadelfiltroSwitchLabel      matlab.ui.control.Label
        FormadelfiltroSwitch           matlab.ui.control.RockerSwitch
        UIAxes2                        matlab.ui.control.UIAxes
        Tab3                           matlab.ui.container.Tab
        GuardarButton                  matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        TiempodeGrabacionLabel         matlab.ui.control.Label
        Propiedadesparaguardar___________Label  matlab.ui.control.Label
        UnidadesdeTiempoDropDownLabel  matlab.ui.control.Label
        UnidadesdeTiempoDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Puerto_serial
        tiempo_de_senial
        senial
        tipos_de_filtros 
        IIR_FIR
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PuertoComEditField
        function PuertoComEditFieldValueChanged(app, event)
            app.Puerto_serial = app.PuertoComEditField.Value;
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            tiva = serialport(app.Puerto_serial, 1000000); %el puerto puede variar entre computadores
            %frecuecia de muestreo en Hz.
            fs = 1000;
            % vectores de datos 
            N = fs*app.TiempodesenialmuestradasEditField.Value;
            app.senial = zeros(1,N);
            n = 1:N;
            flush(tiva);
            for i=n
            app.senial(i) = str2double(readline(tiva));
            end 
            clear tiva
            plot(app.UIAxes,n,app.senial)
           
        end

        % Value changed function: TipodeFiltrosDropDown
        function TipodeFiltrosDropDownValueChanged(app, event)
            app.tipos_de_filtros= app.TipodeFiltrosDropDown.Value;
            
            
            if (app.TipodeFiltrosDropDown.Value =="Butterworth") 
            end
            if (app.TipodeFiltrosDropDown.Value =="Chebyshev") 
            end
            if (app.TipodeFiltrosDropDown.Value =="Elliptic") 
            end


        end

        % Value changed function: FormadelfiltroSwitch
        function FormadelfiltroSwitchValueChanged(app, event)
            app.IIR_FIR = app.FormadelfiltroSwitch.Value;
            if (app.IIR_FIR =="Chebyshev") 
            end
            if (app.IIR_FIR =="Elliptic") 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PuertoComEditFieldLabel = uilabel(app.Tab);
            app.PuertoComEditFieldLabel.Text = 'Puerto Com';
            app.PuertoComEditField = uieditfield(app.Tab, 'text');
            app.PuertoComEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoComEditFieldValueChanged, true);
            app.ConectarButton = uibutton(app.Tab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.TiempodesenialmuestradasEditFieldLabel = uilabel(app.Tab);
            app.TiempodesenialmuestradasEditFieldLabel.Text = 'Tiempo de senial muestrada (s) ';
            app.TiempodesenialmuestradasEditField = uieditfield(app.Tab, 'numeric');
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GananciaLabel = uilabel(app.Tab2);
            app.GananciaLabel.Text = 'Ganancia          ';
            app.GananciaEditField = uieditfield(app.Tab2, 'numeric');
            app.AplicarFiltroButton = uibutton(app.Tab2, 'push');
            app.AplicarFiltroButton.Text = 'Aplicar Filtro ';
            app.FrecunciadecorteHzEditFieldLabel = uilabel(app.Tab2);
            app.FrecunciadecorteHzEditFieldLabel.Text = 'Frecuncia de corte (Hz)';
            app.FrecunciadecorteHzEditField = uieditfield(app.Tab2, 'numeric');
            app.FiltrosDropDownLabel = uilabel(app.Tab2);
            app.FiltrosDropDownLabel.Text = 'Filtros ';
            app.FiltrosDropDown = uidropdown(app.Tab2);
            app.FiltrosDropDown.Items = {'FIR', 'Pasa baja ', 'Pasa altas ', 'Pasa bandas '};
            app.FiltrosDropDown.Value = 'Pasa baja ';
            app.TipodeFiltrosDropDownLabel = uilabel(app.Tab2);
            app.TipodeFiltrosDropDownLabel.Text = 'Tipo de Filtros ';
            app.TipodeFiltrosDropDown = uidropdown(app.Tab2);
            app.TipodeFiltrosDropDown.Items = {'Butterworth', 'Chebyshev', 'Elliptic'};
            app.TipodeFiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeFiltrosDropDownValueChanged, true);
            app.TipodeFiltrosDropDown.Value = 'Butterworth';
            app.FormadelfiltroSwitchLabel = uilabel(app.Tab2);
            app.FormadelfiltroSwitchLabel.Text = 'Forma del filtro ';
            app.FormadelfiltroSwitch = uiswitch(app.Tab2, 'rocker');
            app.FormadelfiltroSwitch.Items = {'IIR', 'FIR'};
            app.FormadelfiltroSwitch.ValueChangedFcn = createCallbackFcn(app, @FormadelfiltroSwitchValueChanged, true);
            app.FormadelfiltroSwitch.Value = 'IIR';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.GuardarButton = uibutton(app.Tab3, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.Slider = uislider(app.Tab3);
            app.Slider.Limits = [0 10];
            app.TiempodeGrabacionLabel = uilabel(app.Tab3);
            app.TiempodeGrabacionLabel.Text = 'Tiempo de Grabacion ';
            app.Propiedadesparaguardar___________Label = uilabel(app.Tab3);
            app.Propiedadesparaguardar___________Label.Text = '     __________ Propiedades para guardar  ___________';
            app.UnidadesdeTiempoDropDownLabel = uilabel(app.Tab3);
            app.UnidadesdeTiempoDropDownLabel.Text = 'Unidades de Tiempo ';
            app.UnidadesdeTiempoDropDown = uidropdown(app.Tab3);
            app.UnidadesdeTiempoDropDown.Items = {'segundos ', 'Milisegundos', 'Microsegundos'};
            app.UnidadesdeTiempoDropDown.Value = 'Milisegundos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRoyectofinal_OA_LJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end